classdef emApparatus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        emAppUIFigure                  matlab.ui.Figure
        ExportTabletoem_expmatButton   matlab.ui.control.Button
        BasicusageTextAreaLabel        matlab.ui.control.Label
        BasicusageTextArea             matlab.ui.control.TextArea
        ElectronemitterPanel           matlab.ui.container.Panel
        VoltageVKnobLabel              matlab.ui.control.Label
        VoltageVKnob                   matlab.ui.control.Knob
        Switch2                        matlab.ui.control.RockerSwitch
        Lamp                           matlab.ui.control.Lamp
        VoltageVGauge                  matlab.ui.control.EditField
        VoltageVGaugeLabel_2           matlab.ui.control.Label
        PowersupplyPanel               matlab.ui.container.Panel
        Switch                         matlab.ui.control.RockerSwitch
        Lamp_2                         matlab.ui.control.Lamp
        CoilcurrentAGaugeLabel_2       matlab.ui.control.Label
        CoilcurrentAGauge              matlab.ui.control.EditField
        CurrentAKnobLabel              matlab.ui.control.Label
        CurrentAKnob                   matlab.ui.control.Knob
        Panel                          matlab.ui.container.Panel
        cmLabel                        matlab.ui.control.Label
        LastrulermeasurementEditFieldLabel  matlab.ui.control.Label
        LastrulermeasurementEditField  matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        VirtualemApparatusLabel        matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
    end

     %#ok<*INUSL> 
     
    properties (Access = private)
        beam
        blueSphere
        rPrev
        rulerP
        hash
        rs = 10; % Radius of the sphere 
        blueSphereLimSize = 8.4;
        blueSphereMaxAlpha = 0.1;
        n = 60
        timerObj % timer
    end

    methods (Access = private)
        
        function timerFunc(app,src,event)
            updateSystem(app);
        end
        
        
        function updateSystem(app)
            
            % ps = power supply, emit = electron emitter
            psOn = app.Switch.Value;
            emitOn = app.Switch2.Value;
            psCurrent = app.CurrentAKnob.Value;
            emitVoltage = app.VoltageVKnob.Value;
            
            % Some random noise for the readings
            randCurrentMax = 0.01;
            psCurrentReading = psCurrent + 2*(rand(1,1)-0.5)*randCurrentMax;
            randVoltageMax = 0.5;
            emitVoltageReading = emitVoltage + 2*(rand(1,1)-0.5)*randVoltageMax;

            %Charge to mass ratio
            em = 1.75882001076e11; 
            %Permeability constant
            u0 = 4*pi*10^-7;
            %Number of turns in coils 
            N = 130;
            %radius of coil
            rCoil = 0.15;
            %Magnetic Field
            B = (8/(5*sqrt(5)))*N*u0*psCurrent/rCoil;
            
            % Expression that relates beam radius to previous quantities
            % little bit of random noise
            randomAdjustment = 2*(rand(1,1)-0.5)*0.025;
            rBeam = sqrt(2*emitVoltage/(B^2*em) )*100  + randomAdjustment ; 
            
            % Check if electron emitter is on
            if( strcmpi(emitOn,'on') )
                app.Lamp.Enable = 'on';
                app.VoltageVGauge.Value = num2str(emitVoltageReading,"%0.2f");
            else
                app.Lamp.Enable = 'off';
                app.VoltageVGauge.Value = "0.00";
            end 
            
            if( strcmpi(psOn,'on') )
                app.Lamp_2.Enable = 'on';
                app.CoilcurrentAGauge.Value =  num2str(psCurrentReading,"%0.2f");
            else
                app.Lamp_2.Enable = 'off';
                app.CoilcurrentAGauge.Value = "0.00";
            end 
            
            if( rBeam >= app.blueSphereLimSize && strcmpi(psOn,'on') && strcmpi(emitOn,'on') ) % Handle the case where the radius is too large
 
                % Glow the sphere a bit
                app.blueSphere.FaceAlpha = app.blueSphereMaxAlpha/( 1 + ( rBeam - app.blueSphereLimSize )^2/10 );
                app.updateBlueSphere(app.blueSphereLimSize)
           
                app.beam.Visible = 'off';
                app.blueSphere.Visible = 'on';
                
            elseif( strcmpi(psOn,'on') && strcmpi(emitOn,'on') )
                app.updateBeam(rBeam);
                app.updateBlueSphere(rBeam)
                app.blueSphere.FaceAlpha = app.blueSphereMaxAlpha; % Max face alpha
                app.beam.Visible = 'on';
                app.blueSphere.Visible = 'on';
                
            else
                app.beam.Visible = 'off';
                app.blueSphere.Visible = 'off';
            end

            
        end
           
        function rulerFunc(app,src,info)
            
            location = info.IntersectionPoint;            
            rulerValue = 10 - location(2);
            locationText = num2str(rulerValue,'%0.2f');
            app.LastrulermeasurementEditField.Value = locationText;
            
        end

        function updateBeam(app, r)

            [Xbr, Ybr, Zbr] = app.calculateBeamShape(r);
            app.beam.XData = Xbr;
            app.beam.YData = Ybr;
            app.beam.ZData = Zbr;
    
            app.rPrev = r;
            
        end
        
        function [Xbr, Ybr, Zbr] = calculateBeamShape(app,r)
                        
            rb1 = r; % Set the size
            
            % these sizes are fixed
            rb2 = rb1+0.4;
            hb = 0.4;
            
            [Xc,Yc,Zc] = app.hollowCyl(rb1,rb2,hb,app.n);
            Zc = Zc - hb/2;
            [Xbr,Ybr,Zbr] = app.basicRotation(Xc,Yc,Zc,90,'y');
            
        end
        

        function updateBlueSphere(app, r)
            [Xsb, Ysb, Zsb] = app.calculateBlueSphereShape(r);
            app.blueSphere.XData = Xsb;
            app.blueSphere.YData = Ysb;
            app.blueSphere.ZData = Zsb;
            
        end
        
        function [Xsb, Ysb, Zsb] = calculateBlueSphereShape(app,r)
            rsb = r+1;
            
            [Xs,Ys,Zs] = sphere(app.n);
            Xsb = Xs*rsb + (1 - rand(size(Xs))/0.5)*0.6;
            Ysb = Ys*rsb + (1 - rand(size(Xs))/0.5)*0.6;
            Zsb = Zs*rsb + (1 - rand(size(Xs))/0.5)*0.6;
        end
        
        function plotBeam(app, r)
            
            [Xbr, Ybr, Zbr] = app.calculateBeamShape(r);
            [Xsb, Ysb, Zsb] = app.calculateBlueSphereShape(r);

            % Draw beam
            app.beam = surf(app.UIAxes,Xbr,Ybr,Zbr,'facecolor',[  0.0941    0.5125    0.99],'edgecolor','none','Facelighting','gouraud','facealpha',0.4,'SpecularStrength',0,'ambientstrength',0.99);
            app.blueSphere = surf(app.UIAxes,Xsb,Ysb,Zsb,'facecolor',[  0.0941    0.6125    0.9561],'edgecolor','none','Facelighting','flat','facealpha',0.1,'SpecularStrength',0.1);

            app.beam.Visible = 'off';
            app.blueSphere.Visible = 'off';
            app.rPrev = r;
        end
        
        
        % SETUP MAIN GRAPH
        function setUpMainGraph(app)
    
            r1 = 15;
            r2 = 17;
            h = 1.5;
            cylShift = 12;
            limMax = 17;
            axisLim = [-1,1,-1,1,-1.5,1]*limMax;
            

            boxw = 32;
            boxPos = [-boxw/2,boxw/2,-boxw/2,boxw/2,-25,-16];
            zshiftBox = -12;   
            z3s = -7;
            boxColor = [0.4,0.4,0.45];
            
            % Coils
            [Xc,Yc,Zc] = app.hollowCyl(r1,r2,h,app.n);
            Zc = Zc - h/2;
            [Xcr,Ycr,Zcr] = app.basicRotation(Xc,Yc,Zc,90,'y');
  
            % Chamber
            [Xs,Ys,Zs] = sphere(app.n);
            Xs = Xs*app.rs;
            Ys = Ys*app.rs;
            Zs = Zs*app.rs;
            Xs(1:7,:)=[];
            Ys(1:7,:)=[];
            Zs(1:7,:)=[];
            
            Xchamber = [Xs(1,:);Xs];
            Ychamber = [Ys(1,:);Ys];
            Zchamber = [Zs(1,:)+z3s;Zs];
                        
            % Control box
            % only use this if "buttons.png" is available to show the panel
            if(exist("empanel.png","file"))
                I = flipud( imread('empanel.png') );
                [cdata,map] = rgb2ind(I, 64);
                szI = size(I)';
                
                xb = linspace(-boxw/2,boxw/2,szI(1));
                zb = linspace(boxw*szI(1)/szI(2)+zshiftBox,boxw*szI(1)/szI(2)*2+zshiftBox,szI(2));
                [Xb,Zb] = meshgrid(xb,zb);
                Zb = Zb - 21; 
                Yb = -16.01*ones(szI(2),szI(1));
                
                h = surface(app.UIAxes,Xb,Yb,Zb,cdata,'EdgeColor','none','FaceColor','texturemap', ...
                    'CDataMapping','direct');
                colormap(app.UIAxes,map)
            else
                warning('empanel.png not found on the search path, so no instrument panel is plotted.')
            end

            % Pre-plot settings
            set(app.UIAxes,'XColor', 'none','YColor','none','ZColor','none')
            set(app.UIAxes,'Color',[0.15,0.15,0.15])
            hold(app.UIAxes,'on')
            material(app.UIAxes,'shiny')
            
            % Draw control box
            ax = app.UIAxes;    
            patchObj = app.box( app.UIAxes, boxPos(1), boxPos(2), boxPos(3), boxPos(4), boxPos(5), boxPos(6), boxColor);
    
            % Draw the helmholtz coil
            surf(app.UIAxes,Xcr-cylShift,Ycr,Zcr,'facecolor',[  0.8333    0.5208    0.3317],'edgecolor','none','Facelighting','flat','facealpha',1,'SpecularStrength',0.05)
            surf(app.UIAxes,Xcr+cylShift,Ycr,Zcr,'facecolor',[  0.8333    0.5208    0.3317],'edgecolor','none','Facelighting','flat','facealpha',1,'SpecularStrength',0.05)
            
            % Draw the chamber
            surf(app.UIAxes,Xchamber,Ychamber,Zchamber,'facecolor',[  0.95   0.95    0.95],'edgecolor','none','Facelighting','flat','facealpha',0.3,'SpecularStrength',1,'ambientstrength',0.5)
            
            % Draw ruler
            yval = 17;
            zval = 0.75;
            zshift = -1;
            app.rulerP = patch(app.UIAxes, -13.1*ones(1,5), yval*[-1,1,1,-1,-1], zval*[-1,-1,1,1,-1] + zshift, 'w','edgecolor','none','ButtonDownFcn',@app.rulerFunc);
            app.hash = plot3(app.UIAxes, -13.1*ones(2,21), repmat(-10:10,2,1), [zval*ones(1,21); zval*ones(1,21)/4]+zshift,'k','ButtonDownFcn',@app.rulerFunc);
            text(app.UIAxes,-13.6, 10, zshift  , '0', 'color', 'k', 'fontsize', 12, 'BackgroundColor','none', 'HorizontalAlignment',"center")
            text(app.UIAxes,-13.6, -10, zshift  , '20', 'color', 'k', 'fontsize', 12, 'BackgroundColor','none', 'HorizontalAlignment',"center")
            
            % Draw beam
            plotBeam(app, 0);
            
            % Settings
            lighting(app.UIAxes,'gouraud')
            light(app.UIAxes,'Position',[-10 -30 0],'Style','local')
            light(app.UIAxes,'Position',[-40 30 10],'Style','local','color',[0.5,0.5,0.5])
            axis(app.UIAxes,'equal')
            axis(app.UIAxes,axisLim)
            view(app.UIAxes,-50,15)
           
            
        end
        
        % HELPER FUNCTIONS
        function patchObj = box( app, axesHandle, x0, x1, y0, y1, z0, z1, color )
            vert = [x0 y0 z0 ;x1 y0 z0; x1 y1 z0; x0 y1 z0; x0 y0 z1; x1 y0 z1; x1 y1 z1; x0 y1 z1];
            fac = [1 2 6 5;2 3 7 6;3 4 8 7;4 1 5 8;1 2 3 4;5 6 7 8];
            patchObj = patch( axesHandle,'Vertices',vert,'Faces',fac,'facevertexcdata',repmat(color,8,1),'edgecolor','none','facecolor','interp');    
        end
        function [X,Y,Z] = hollowCyl( app, r1, r2, h, n )
            [X1,Y1,Z1] = cylinder(r1,n);
            [X2,Y2,Z2] = cylinder(r2,n);
            X = [X1;X2(end:-1:1,:);X1(1,:)];
            Y = [Y1;Y2(end:-1:1,:);Y1(1,:)];
            Z = [Z1;Z2(end:-1:1,:);Z1(1,:)]*h;
        end
        % Select axis: x, y, or z
        function [Xr, Yr, Zr] = basicRotation( app, X, Y, Z, thetaDeg, axis )
            R = eye(3);
            if(strcmp(axis,'x'))
                R = [1,0,0; 0, cosd(thetaDeg), -sind(thetaDeg); 0, sind(thetaDeg), cosd(thetaDeg)];
            elseif(strcmp(axis,'y'))
                R = [cosd(thetaDeg), 0, sind(thetaDeg); 0, 1, 0; -sind(thetaDeg), 0, cosd(thetaDeg)];
            elseif(strcmp(axis,'z'))
                R = [cosd(thetaDeg), -sind(thetaDeg), 0; sind(thetaDeg), cosd(thetaDeg), 0; 0, 0, 1];        
            end
            Xr = R(1,1)*X + R(1,2)*Y + R(1,3)*Z;
            Yr = R(2,1)*X + R(2,2)*Y + R(2,3)*Z;
            Zr = R(3,1)*X + R(3,2)*Y + R(3,3)*Z;
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Always add the app to the path to avoid timer errors
            filePathName = split(string(mfilename('fullpath')),filesep);
            filePath = join(filePathName(1:end-1),filesep);
            addpath(filePath);
           
            app.setUpMainGraph
            app.updateSystem
            
            axtoolbar(app.UIAxes,{'rotate','restoreview'},'Visible','on');
            disableDefaultInteractivity(app.UIAxes);
           
            app.timerObj = timer('TimerFcn', @timerFuncWrapper, 'Period', 0.2, 'ExecutionMode', 'fixedSpacing', 'BusyMode', 'drop');
            start(app.timerObj);

            app.UITable.Data = NaN(10,2);
            
            function timerFuncWrapper(src,event)
                try
                    timerFunc(app,src,event);
                catch ME
                    warning("The application file emApparatus.mlapp is not on the path. Was the path changed?" + ...
                            newline + newline + "Closing emApparatus.mlapp." + newline)
                    timerObjs = timerfind;
                    stop(timerObjs);    
                    drawnow
                    
                    try
                        cd(filePath)
                        evalin("base","appHandle = findall(findall(0,'Type', 'figure'),'Name','e/m App');");
                        evalin("base","close(appHandle.RunningAppInstance.emAppUIFigure); clear appHandle");
                    catch ME
                        error("Unable to close the app figure. Close it manually.")
                    end
                        
                        
                    disp("Reopen the app to start again. Do not adjust the path while running the app.")
                end
            end
        end

        % Value changed function: VoltageVKnob
        function VoltageVKnobValueChanged(app, event)
            %app.updateSystem
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            %app.updateSystem
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            %app.updateSystem
        end

        % Callback function
        function VoltageKnobValueChanged(app, event)
            %app.updateSystem
        end

        % Value changed function: CurrentAKnob
        function CurrentAKnobValueChanged(app, event)
            %app.updateSystem
        end

        % Button pushed function: ExportTabletoem_expmatButton
        function ExportTabletoem_expmatButtonPushed(app, event)
            
            var1 = app.UITable.Data(:,1);
            var2 = app.UITable.Data(:,2);
            
            current_amp = var1(~isnan(var1),1);
            radius_cm = var2(~isnan(var2),1);
            
            save em_exp.mat current_amp radius_cm -mat
        end

        % Close request function: emAppUIFigure
        function emAppUIFigureCloseRequest(app, event)
            stop(app.timerObj)
            delete(app.timerObj)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.emAppUIFigure = uifigure('Visible', 'off');
            app.emAppUIFigure.Name = 'e/m App';
            app.emAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @emAppUIFigureCloseRequest, true);
            app.ExportTabletoem_expmatButton = uibutton(app.emAppUIFigure, 'push');
            app.ExportTabletoem_expmatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTabletoem_expmatButtonPushed, true);
            app.ExportTabletoem_expmatButton.Text = 'Export Table to em_exp.mat';
            app.BasicusageTextAreaLabel = uilabel(app.emAppUIFigure);
            app.BasicusageTextAreaLabel.Text = 'Basic usage';
            app.BasicusageTextArea = uitextarea(app.emAppUIFigure);
            app.BasicusageTextArea.Editable = 'off';
            app.BasicusageTextArea.Value = {'- To generate an electron beam '; '   1. Turn on the electron emitter'; '   2. Turn on the power supply '; ''; '- Adjust the voltage and current'; '   This affects the beam radius'; ''; '- Click the ruler to show a '; 'measurement'; ''; '- Enter measurements in the table'; ''; '- Save measurements to MAT-file'; ''};
            app.ElectronemitterPanel = uipanel(app.emAppUIFigure);
            app.ElectronemitterPanel.Title = 'Electron emitter';
            app.VoltageVKnobLabel = uilabel(app.ElectronemitterPanel);
            app.VoltageVKnobLabel.Text = 'Voltage [V]';
            app.VoltageVKnob = uiknob(app.ElectronemitterPanel, 'continuous');
            app.VoltageVKnob.Limits = [0 400];
            app.VoltageVKnob.MajorTicks = [0 100 200 300 400];
            app.VoltageVKnob.MajorTickLabels = {'0', '100', '200', '300', '400'};
            app.VoltageVKnob.ValueChangedFcn = createCallbackFcn(app, @VoltageVKnobValueChanged, true);
            app.VoltageVKnob.MinorTicks = [];
            app.Switch2 = uiswitch(app.ElectronemitterPanel, 'rocker');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Lamp = uilamp(app.ElectronemitterPanel);
            app.Lamp.Enable = 'off';
            app.VoltageVGauge = uieditfield(app.ElectronemitterPanel, 'text');
            app.VoltageVGauge.Editable = 'off';
            app.VoltageVGauge.Value = '0.00';
            app.VoltageVGaugeLabel_2 = uilabel(app.ElectronemitterPanel);
            app.VoltageVGaugeLabel_2.Text = 'Voltage [V]';
            app.PowersupplyPanel = uipanel(app.emAppUIFigure);
            app.PowersupplyPanel.Title = 'Power supply';
            app.Switch = uiswitch(app.PowersupplyPanel, 'rocker');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp_2 = uilamp(app.PowersupplyPanel);
            app.Lamp_2.Enable = 'off';
            app.CoilcurrentAGaugeLabel_2 = uilabel(app.PowersupplyPanel);
            app.CoilcurrentAGaugeLabel_2.Text = 'Coil current [A]';
            app.CoilcurrentAGauge = uieditfield(app.PowersupplyPanel, 'text');
            app.CoilcurrentAGauge.Editable = 'off';
            app.CoilcurrentAGauge.Value = '0.00';
            app.CurrentAKnobLabel = uilabel(app.PowersupplyPanel);
            app.CurrentAKnobLabel.Text = 'Current [A]';
            app.CurrentAKnob = uiknob(app.PowersupplyPanel, 'continuous');
            app.CurrentAKnob.Limits = [0 4];
            app.CurrentAKnob.MajorTicks = [0 1 2 3 4];
            app.CurrentAKnob.MajorTickLabels = {'0', '1', '2', '3', '4'};
            app.CurrentAKnob.ValueChangedFcn = createCallbackFcn(app, @CurrentAKnobValueChanged, true);
            app.CurrentAKnob.MinorTicks = [];
            app.Panel = uipanel(app.emAppUIFigure);
            app.cmLabel = uilabel(app.Panel);
            app.cmLabel.Text = 'cm';
            app.LastrulermeasurementEditFieldLabel = uilabel(app.Panel);
            app.LastrulermeasurementEditFieldLabel.Text = 'Last ruler measurement:';
            app.LastrulermeasurementEditField = uieditfield(app.Panel, 'text');
            app.LastrulermeasurementEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.VirtualemApparatusLabel = uilabel(app.emAppUIFigure);
            app.VirtualemApparatusLabel.Text = 'Virtual e/m Apparatus';
            app.UITable = uitable(app.emAppUIFigure);
            app.UITable.ColumnName = {'Current [A]'; 'Radius [cm]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            % Show the figure after all components are created
            app.emAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = emApparatus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.emAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.emAppUIFigure)
        end
    end
end